classdef IHM_FINAL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DviewMenu                      matlab.ui.container.Menu
        InfoMenu                       matlab.ui.container.Menu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
        Panel                          matlab.ui.container.Panel
        Lamp                           matlab.ui.control.Lamp
        StartButton                    matlab.ui.control.Button
        PauseButton                    matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        DronesperdusEditField          matlab.ui.control.NumericEditField
        DronesperdusEditFieldLabel     matlab.ui.control.Label
        EventLoggingLabel              matlab.ui.control.Label
        TempscoulLabel                 matlab.ui.control.Label
        DronesnondploysEditField       matlab.ui.control.NumericEditField
        DronesnondploysEditFieldLabel  matlab.ui.control.Label
        DronesdploysEditField          matlab.ui.control.NumericEditField
        DronesdploysEditFieldLabel     matlab.ui.control.Label
        dtSlider                       matlab.ui.control.Slider
        dtSliderLabel                  matlab.ui.control.Label
        tempscouleEditField            matlab.ui.control.EditField
        Console                        matlab.ui.control.TextArea
        TabGroup                       matlab.ui.container.TabGroup
        ObservationTab                 matlab.ui.container.Tab
        PanDNButton                    matlab.ui.control.Button
        PanUPButton                    matlab.ui.control.Button
        RotationGAButton               matlab.ui.control.Button
        RotationDRButton               matlab.ui.control.Button
        TranslationGAButton            matlab.ui.control.Button
        TranslationARButton            matlab.ui.control.Button
        TranslationDRButton            matlab.ui.control.Button
        TranslationAVButton            matlab.ui.control.Button
        FlotteTab                      matlab.ui.container.Tab
        SuppressionDroneButton         matlab.ui.control.Button
        ModificationDroneButton        matlab.ui.control.Button
        AjoutDroneButton               matlab.ui.control.Button
        UIFlotteTable                  matlab.ui.control.Table
        ZonesTab                       matlab.ui.container.Tab
        SuppressionZoneButton          matlab.ui.control.Button
        ModificationZoneButton         matlab.ui.control.Button
        AjoutZoneButton                matlab.ui.control.Button
        UIZoneTable                    matlab.ui.control.Table
        CiblesTab                      matlab.ui.container.Tab
        SuppressionCibleButton         matlab.ui.control.Button
        ModificationCibleButton        matlab.ui.control.Button
        AjoutCibleButton               matlab.ui.control.Button
        UICibleTable                   matlab.ui.control.Table
        ComportementTab                matlab.ui.container.Tab
        UIAxes3D                       matlab.ui.control.UIAxes
        UIAxes2D                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        plotHandles  % struct to store scatter, quiver, trace objects
        simTimer     % MATLAB timer for repeated updates

        env                  % Environment object
        swarm                % SwarmManager object
        %simTimer             % Timer for the simulation loop
        simulationRunning    logical = false
        simulationPaused     logical = false
    
        % For real-time plotting
        dronePlotHandles     = []   % Store handles to drone plots (2D/3D)
        traceSize            = 20
        simulationTime       double = 0
        maxSimulationTime    double = 100000
    end
    
    methods (Access = public)
    
        function setupSimulation(app)
            clc
            
            % 1) Simulation parameters
            app.traceSize = 200;
            app.maxSimulationTime = 100000;
            
            % 2) Reset UI elements
            app.Console.Value         = {''};
            app.tempscouleEditField.Value = "0";
            app.simulationTime        = 0;
            app.DronesdploysEditField.Value    = 0;  % or set to known value
            app.DronesnondploysEditField.Value = 0;
            app.DronesperdusEditField.Value    = 0;
            
            % 3) Create Environment/Swarm
            app.env = Environment(10, 200, ...
                [-200, 200, 200, -200], ...
                [-200, -200, 200, 200], ...
                [0,0,0,0]);
            
            app.swarm = SwarmManager(app.env, app.maxSimulationTime);
            
            % ... (add your drones, set waypoints, targets, etc.)
            % Example code (as in your snippet):
            numMultirotor = 10;
            numFixedwing  = 5;
            homeBaseCoord = [0 0 0];
            for i = 1:numMultirotor
                app.swarm.addDrone('multirotor', homeBaseCoord);
                app.swarm.MultiRotor{i}.mode_Follow_waypoint = false;
            end
            for i = 1:numFixedwing
                app.swarm.addDrone('fixedwing', homeBaseCoord);
                app.swarm.FixedWing{i}.mode_Follow_waypoint = false;
            end
            
            Waypoints = [0 50 100; 0 0 50; 100 100 50 ; 100 -100 100 ; -100 -100 50 ; -100 100 100 ; -100 -10 10]; 
            for i = 1:length(app.swarm.Drones)
                app.swarm.Drones{i}.Waypoints = Waypoints;
            end
            
            
            % POUR BAPTISTE, switch des targets et groupes d'observation
            app.swarm.targets = [1 0 0; 140 140 30; 6 8 7]; % 3 groupes de target
            Target = [0 0 75];
            app.swarm.update_target(Target, 1); % Pour modifier la target du pool n°1
            app.swarm.Drones{end}.setTargetGroup(2); % Pour modifier le pool de target du dernier drone (il passe en pool 2)
            app.swarm.Drones{1}.mode_Follow_waypoint = true; % Le premier drone passe en follow waypoint
            app.swarm.AliveDrones; % Liste des drones vivants
            
            
            app.swarm.r = [30 60 100]; % Rayons Répulsion, attraction au sein de l'essaim
            app.swarm.swarm_weights = [1.4 0.8]; % Pondérations répulsion, attraction au sein de l'essaim
            app.swarm.weights = [0.5 1.2 1 10] / 10; % pondération essaim, inertie, target, évitement
                        
            % 4) Clear and prepare tables
            app.UIFlotteTable.Data = {};
            app.UIZoneTable.Data   = {};
            app.UICibleTable.Data  = {};

            cla(app.UIAxes3D,"reset");
            
            % 5) Prepare axes
            title(app.UIAxes3D, 'Affichage 3D');
            xlabel(app.UIAxes3D, 'X'); ylabel(app.UIAxes3D, 'Y'); zlabel(app.UIAxes3D, 'Z');
            
            title(app.UIAxes2D, 'Affichage 2D');
            xlabel(app.UIAxes2D, 'X'); ylabel(app.UIAxes2D, 'Y');
            zticks(app.UIAxes2D, []);
            
            % 6) Initialize plot handles but DO NOT start the timer here
            app.plotHandles = RTPlot2_init(app, app.UIAxes3D, app.env, app.swarm, Target, app.traceSize);
            
            % --- Removed code that starts or re-creates the timer here. ---
            % We let startSimulationTimer(app) handle it instead.
        end

        function startSimulationTimer(app)
            if isempty(app.simTimer) || ~isvalid(app.simTimer)
                % Create a new timer
                app.simTimer = timer( ...
                    'ExecutionMode','fixedRate', ...
                    'Period', app.dtSlider.Value, ...
                    'BusyMode','drop', ...
                    'TimerFcn', @(~,~)updateSimulation(app));
            else
                % If it already exists and is running, stop it first
                if strcmpi(app.simTimer.Running, 'on')
                    stop(app.simTimer);
                end
                app.simTimer.Period = app.dtSlider.Value;
                app.simTimer.TimerFcn = @(~,~)updateSimulation(app);
            end
        
            start(app.simTimer);
            app.simulationRunning = true;
            app.simulationPaused  = false;
            app.Lamp.Color = [0 1 0];  % Green
            addConsoleMsg(app, "Simulation started.");
        end

        function updateSimulation(app)
            if ~app.simulationPaused
                dt = 0.1; %app.dtSlider.Value
                app.simulationTime = app.simulationTime + dt;
        
                % Advance swarm by dt
                app.swarm.update_speeds(dt);
        
                % Update the visuals (no 'Target' argument here)
                RTPlot2_update(app, app.plotHandles, app.swarm);
                % drawnow limitrate;
        
                % Update UI fields
                app.tempscouleEditField.Value = sprintf("%.1f", app.simulationTime);
        
                % Optional checks / updates ...
                if app.simulationTime >= app.maxSimulationTime
                    stopSimulationTimer(app);
                end
            end
        end

        function handles = RTPlot2_init(app, ax, env, swarm, Target, traceSize)
        % RTPlot2_init  Initialize 3D plots for environment, zones, and drones.
        %   ax        - Axes handle (UIAxes or standard axes)
        %   env       - Environment object (with GroundCoordinates, ZonesList, etc.)
        %   swarm     - SwarmManager object (with Drones array, etc.)
        %   Target    - Initial target coordinate(s), Nx3
        %   traceSize - Number of past positions to display in the "trace"
        
            %== 1) Basic Axes Setup =======================================
            rotate3d(ax, 'on');
            grid(ax, 'on');
            hold(ax, 'on');
            axis(ax, 'equal');            % Keep aspect ratio equal in X/Y/Z
            view(ax, -75, 15);            % Choose an initial 3D viewpoint
        
            % Let MATLAB manage axis limits automatically
            set(ax, ...
                'XLimMode','auto', ...
                'YLimMode','auto', ...
                'ZLimMode','auto');
        
            xlabel(ax, 'X (Nord - Sud)');
            ylabel(ax, 'Y (Ouest - Est)');
            zlabel(ax, 'Z (Altitude)');
            title(ax, 'Rendu de Simulation en Temps Réel');
        
            %== 2) Draw Ground Surface & Zones =============================
            fill3(ax, ...
                env.GroundCoordinates.x, ...
                env.GroundCoordinates.y, ...
                env.GroundCoordinates.z, ...
                [0.5 0.5 0.5], 'FaceAlpha', 0.3);
        
            % Example of drawing each zone (adapt to your zone logic)
            for iZone = 1:length(env.ZonesList)
                zone = env.ZonesList{iZone};
                % Choose a color based on category
                switch zone.Category
                    case 'A', zoneColor = 'g';
                    case 'P', zoneColor = 'r';
                    case 'M', zoneColor = 'b';
                    otherwise, zoneColor = 'y';
                end
                % create_shape is your custom function
                create_shape(zone.Type, zoneColor, ...
                             zone.CenterPosition, zone.Dimensions, ...
                             zone.TiltAngle, ax);
            end
        
            %== 3) Plot the Target(s) ======================================
            nTargets = size(Target, 1);
            targetHandles = gobjects(1, nTargets);
            for k = 1:nTargets
                targetHandles(k) = scatter3(ax, ...
                    Target(k,1), Target(k,2), Target(k,3), ...
                    50, 'filled', 'MarkerFaceColor','r');
            end
        
            %== 4) Plot Each Drone (head + quiver + trace) =================
            nDrones = numel(swarm.Drones);
            droneHeads   = gobjects(1, nDrones);
            droneQuivers = gobjects(1, nDrones);
            droneTraces  = gobjects(1, nDrones);
        
            for i = 1:nDrones
                % Current position
                pos = swarm.Drones{i}.posState;    % [x, y, z]
                droneHeads(i) = scatter3(ax, ...
                    pos(1), pos(2), pos(3), 50, ...
                    'filled', 'MarkerFaceColor','b');
        
                droneQuivers(i) = quiver3(ax, ...
                    pos(1), pos(2), pos(3), ...
                    0, 0, 0, 'r', 'LineWidth', 1.5, 'MaxHeadSize', 0.5);
        
                droneTraces(i) = plot3(ax, nan, nan, nan, ...
                    'Color', [0 0 1], 'LineStyle', '--');
            end
        
            %== 5) Package into a "handles" struct ==========================
            handles.ax             = ax;
            handles.targetHandles  = targetHandles;
            handles.droneHeads     = droneHeads;
            handles.droneQuivers   = droneQuivers;
            handles.droneTraces    = droneTraces;
            handles.traceSize      = traceSize;
            handles.speedVectorSize = 2;  % adjust as needed
        end

        function RTPlot2_update(app, handles, swarm)
            ax = handles.ax;
            speedVectorSz = handles.speedVectorSize;
            traceSize     = handles.traceSize;
            
            % --- 1) Update Drones' positions as usual ---
            nDrones = numel(swarm.Drones);
            allPositions = [];  % collect all drone positions here
            for i = 1:nDrones
                pos = swarm.Drones{i}.posState;     % [x, y, z]
                spd = swarm.Drones{i}.speedState;
                
                % Update scatter/quiver
                set(handles.droneHeads(i), ...
                    'XData', pos(1), 'YData', pos(2), 'ZData', pos(3));
                set(handles.droneQuivers(i), ...
                    'XData', pos(1), 'YData', pos(2), 'ZData', pos(3), ...
                    'UData', spd(1)*speedVectorSz, ...
                    'VData', spd(2)*speedVectorSz, ...
                    'WData', spd(3)*speedVectorSz);
                
                % Update traces
                if isprop(swarm.Drones{i}, 'posLog')
                    lastPositions = swarm.Drones{i}.posLog;
                    if ~isempty(lastPositions)
                        idx = max(1, size(lastPositions,1)-traceSize+1) : size(lastPositions,1);
                        recentPositions = lastPositions(idx, :);
                        set(handles.droneTraces(i), ...
                            'XData', recentPositions(:,1), ...
                            'YData', recentPositions(:,2), ...
                            'ZData', recentPositions(:,3));
                    end
                end
                
                % Collect position for bounding box
                allPositions = [allPositions; pos(:).']; %#ok<AGROW>
            end
            
            % --- 2) Also include environment corners, target positions, etc. ---
            envX = app.env.GroundCoordinates.x(:);
            envY = app.env.GroundCoordinates.y(:);
            envZ = app.env.GroundCoordinates.z(:);
            
            allPositions = [allPositions; [envX, envY, envZ]];
            
            % If you want to include your target positions in the bounding box:
            % Suppose you have them stored somewhere, e.g. swarm.targets
            if ~isempty(app.swarm.targets)
                allPositions = [allPositions; app.swarm.targets];
            end
            
            % If we have no data, choose some default
            if isempty(allPositions)
                minX = -10; maxX = 10;
                minY = -10; maxY = 10;
                minZ = 0;   maxZ = 100;
            else
                minX = min(allPositions(:,1));  maxX = max(allPositions(:,1));
                minY = min(allPositions(:,2));  maxY = max(allPositions(:,2));
                minZ = min(allPositions(:,3));  maxZ = max(allPositions(:,3));
            end
            
            % Add a small margin so the drones don't sit exactly on the axis edge
            margin = 10;  % or whatever you like
            XLim = [minX - margin, maxX + margin];
            YLim = [minY - margin, maxY + margin];
            ZLim = [minZ - margin, maxZ + margin];
            
            % Force those new limits:
            set(ax, 'XLim', XLim, 'YLim', YLim, 'ZLim', ZLim);
            axis(ax, 'equal');
            
            % 3) Render
            drawnow limitrate;
        end
   
        function stopSimulationTimer(app)
            if ~isempty(app.simTimer) && isvalid(app.simTimer)
                stop(app.simTimer);
            end
            app.simulationRunning = false;
            app.Lamp.Color = [1 0 0];  % Red
            addConsoleMsg(app, "Simulation stopped.");
        end

        % Method to add a message to the Console
        function addConsoleMsg(app, message)
            % Get the current timestamp
            timestamp = datetime("now");
            
            % Format the message with timestamp
            formattedMsg = sprintf('[%s] %s', timestamp, message);
            
            % Append the message to the Console
            currentConsoleValue = app.Console.Value;
            if ischar(currentConsoleValue)
                % Convert single string to cell array if needed
                currentConsoleValue = {currentConsoleValue};
            end
            app.Console.Value = [currentConsoleValue; {formattedMsg}];
            
            % Ensure UI updates immediately
            %drawnow;
        end

        % Callback function for the "Suppression" button
        function onSuppressionButtonPushed(app, ~)
            % Create a new figure for deleting data
            supprFig = uifigure('Name', 'Supprimer', 'Position', [100, 100, 300, 150]);

            % Add dropdown menu to select existing drone
            uilabel(supprFig, 'Position', [20, 70, 100, 22], 'Text', 'Nom:');
            nomDropdown = uidropdown(supprFig, 'Position', [130, 70, 150, 22]);
            nomDropdown.Items = app.UITable.Data(:, 1); % Populate dropdown with existing names

            % OK button
            okButton = uibutton(supprFig, 'Text', 'OK', 'Position', [100, 30, 100, 30]);
            okButton.ButtonPushedFcn = @(~, ~) deleteDataCallback(app, supprFig, nomDropdown);
        end

        % Callback to add data
        function addDataCallback(app, fig, nomField, coordField)
            % Retrieve data from fields
            nom = nomField.Value;
            coord = coordField.Value;

            % Add new row to the table
            newRow = {nom, coord};
            app.UIFlotteTable.Data = [app.UIFlotteTable.Data; newRow];

            % Close the window
            delete(fig);
        end

        % Callback to modify data
        function modifyDataCallback(app, fig, nomDropdown, coordField)
            % Retrieve the selected drone name and the new coordinates
            nom       = nomDropdown.Value;
            newCoord  = coordField.Value;  % e.g. '100,200,50' if it's a string
            
            % Load the existing data from the correct table
            data = app.UIFlotteTable.Data;  % <--- fix here
            
            % Find matching name and update
            for i = 1:size(data, 1)
                if strcmp(data{i, 1}, nom)
                    data{i, 2} = newCoord;  % Assuming column #2 is for coordinates
                    break;
                end
            end
            
            % Write the table back
            app.UIFlotteTable.Data = data;
            
            % Close the window
            delete(fig);
        end

        % Callback to delete data
        function deleteDataCallback(app, fig, nomDropdown)
            % Retrieve data from dropdown
            nom = nomDropdown.Value;

            % Find the row to delete
            data = app.UIFlotteTable.Data;
            rowToDelete = false(size(data, 1), 1);
            for i = 1:size(data, 1)
                if strcmp(data{i, 1}, nom)
                    rowToDelete(i) = true;
                    break;
                end
            end

            % Remove the row and update the table
            data(rowToDelete, :) = [];
            app.UIFlotteTable.Data = data;

            % Close the window
            delete(fig);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if ~app.simulationRunning
                % Initialize or re-initialize the simulation
                setupSimulation(app);
                startSimulationTimer(app);
            elseif app.simulationPaused
                % If it's paused, just unpause it
                app.simulationPaused = false;
                app.Lamp.Color = [0 1 0];
                addConsoleMsg(app, "Simulation resumed.");
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if app.simulationRunning
                stopSimulationTimer(app);
            end
            % Re-initialize everything
            setupSimulation(app);
            app.Lamp.Color = [1 0 0];
            cla(app.UIAxes3D,"reset");
            addConsoleMsg(app, "Simulation reset.");
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            if app.simulationRunning && ~app.simulationPaused
                app.simulationPaused = true;
                app.Lamp.Color = [1 1 0]; % Yellow
                addConsoleMsg(app, "Simulation paused.");
            elseif app.simulationRunning && app.simulationPaused
                % If paused, pressing Pause again could resume or do nothing
                app.simulationPaused = false;
                app.Lamp.Color = [0 1 0];
                addConsoleMsg(app, "Simulation resumed.");
            end
        end

        % Button pushed function: SuppressionDroneButton
        function SuppressionDroneButtonPushed(app, event)
            % Create a new figure for deleting data
            supprFig = uifigure('Name', 'Supprimer', 'Position', [100, 100, 300, 150]);

            % Add dropdown menu to select existing drone
            uilabel(supprFig, 'Position', [20, 70, 100, 22], 'Text', 'Nom:');
            nomDropdown = uidropdown(supprFig, 'Position', [130, 70, 150, 22]);
            nomDropdown.Items = app.UIFlotteTable.Data(:, 1); % Populate dropdown with existing names

            % OK button
            okButton = uibutton(supprFig, 'Text', 'OK', 'Position', [100, 30, 100, 30]);
            okButton.ButtonPushedFcn = @(~, ~) deleteDataCallback(app, supprFig, nomDropdown);
        end

        % Button pushed function: ModificationDroneButton
        function ModificationDroneButtonPushed(app, event)
            % Create a new figure for modifying data
            modifFig = uifigure('Name', 'Modifier', 'Position', [100, 100, 300, 200]);

            % Add dropdown menu to select existing drone
            uilabel(modifFig, 'Position', [20, 150, 100, 22], 'Text', 'Nom:');
            nomDropdown = uidropdown(modifFig, 'Position', [130, 150, 150, 22]);
            nomDropdown.Items = app.UIFlotteTable.Data(:, 1); % Populate dropdown with existing names

            uilabel(modifFig, 'Position', [20, 100, 150, 22], 'Text', 'Nouvelles Coordonnées:');
            coordField = uieditfield(modifFig, 'Position', [130, 100, 150, 22]);

            % OK button
            okButton = uibutton(modifFig, 'Text', 'OK', 'Position', [100, 30, 100, 30]);
            okButton.ButtonPushedFcn = @(~, ~) modifyDataCallback(app, modifFig, nomDropdown, coordField);
        end

        % Button pushed function: AjoutDroneButton
        function AjoutDroneButtonPushed(app, event)
            % Create a new figure for adding data
            ajoutFig = uifigure('Name', 'Ajouter', 'Position', [100, 100, 300, 200]);

            % Add input fields for data
            uilabel(ajoutFig, 'Position', [20, 150, 100, 22], 'Text', 'Nom:');
            nomField = uieditfield(ajoutFig, 'Position', [130, 150, 150, 22]);

            uilabel(ajoutFig, 'Position', [20, 100, 100, 22], 'Text', 'Coordonnées:');
            coordField = uieditfield(ajoutFig, 'Position', [130, 100, 150, 22]);

            % OK button
            okButton = uibutton(ajoutFig, 'Text', 'OK', 'Position', [100, 30, 100, 30]);
            okButton.ButtonPushedFcn = @(~, ~) addDataCallback(app, ajoutFig, nomField, coordField);
        end

        % Value changed function: dtSlider
        function dtSliderValueChanged(app, event)
            % Get the new value of dt from the slider
            newDt = app.dtSlider.Value;
            
            % Update the timer's period if the simulation is running
            if ~isempty(app.simTimer) && isvalid(app.simTimer)
                if strcmp(app.simTimer.Running, 'on')
                    stop(app.simTimer); % Stop the timer before changing
                    app.simTimer.Period = newDt; % Update the timer's period
                    start(app.simTimer); % Restart the timer
                else
                    app.simTimer.Period = newDt; % Update the timer's period
                end
            end
            
            % Log the change to the console
            addConsoleMsg(app, sprintf('Time step (dt) updated to %.2f seconds.', newDt));            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DviewMenu = uimenu(app.UIFigure);
            app.DviewMenu.Text = '3Dview';
            app.InfoMenu = uimenu(app.UIFigure);
            app.InfoMenu.Text = 'Info';
            app.Menu = uimenu(app.InfoMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.InfoMenu);
            app.Menu2.Text = 'Menu2';
            app.UIAxes2D = uiaxes(app.UIFigure);
            title(app.UIAxes2D, 'Affichage 2D')
            xlabel(app.UIAxes2D, 'X')
            ylabel(app.UIAxes2D, 'Y')
            app.UIAxes2D.ZTick = [];
            app.UIAxes3D = uiaxes(app.UIFigure);
            title(app.UIAxes3D, 'Affichage 3D')
            xlabel(app.UIAxes3D, 'X')
            ylabel(app.UIAxes3D, 'Y')
            zlabel(app.UIAxes3D, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ObservationTab = uitab(app.TabGroup);
            app.ObservationTab.Title = 'Observation';
            app.TranslationAVButton = uibutton(app.ObservationTab, 'push');
            app.TranslationAVButton.Text = 'Translation AV';
            app.TranslationDRButton = uibutton(app.ObservationTab, 'push');
            app.TranslationDRButton.Text = 'Translation DR';
            app.TranslationARButton = uibutton(app.ObservationTab, 'push');
            app.TranslationARButton.Text = 'Translation AR';
            app.TranslationGAButton = uibutton(app.ObservationTab, 'push');
            app.TranslationGAButton.Text = 'Translation GA';
            app.RotationDRButton = uibutton(app.ObservationTab, 'push');
            app.RotationDRButton.Text = 'Rotation DR';
            app.RotationGAButton = uibutton(app.ObservationTab, 'push');
            app.RotationGAButton.Text = 'Rotation GA';
            app.PanUPButton = uibutton(app.ObservationTab, 'push');
            app.PanUPButton.Text = 'Pan UP';
            app.PanDNButton = uibutton(app.ObservationTab, 'push');
            app.PanDNButton.Text = 'Pan DN';
            app.FlotteTab = uitab(app.TabGroup);
            app.FlotteTab.Title = 'Flotte';
            app.UIFlotteTable = uitable(app.FlotteTab);
            app.UIFlotteTable.ColumnName = {'Liste'; 'Coordonnées'; 'Type'; 'Statut'; 'Phase de Mission'; 'Cible Allouée'};
            app.UIFlotteTable.RowName = {};
            app.AjoutDroneButton = uibutton(app.FlotteTab, 'push');
            app.AjoutDroneButton.ButtonPushedFcn = createCallbackFcn(app, @AjoutDroneButtonPushed, true);
            app.AjoutDroneButton.Text = 'Ajout';
            app.ModificationDroneButton = uibutton(app.FlotteTab, 'push');
            app.ModificationDroneButton.ButtonPushedFcn = createCallbackFcn(app, @ModificationDroneButtonPushed, true);
            app.ModificationDroneButton.Text = 'Modification';
            app.SuppressionDroneButton = uibutton(app.FlotteTab, 'push');
            app.SuppressionDroneButton.ButtonPushedFcn = createCallbackFcn(app, @SuppressionDroneButtonPushed, true);
            app.SuppressionDroneButton.Text = 'Suppression';
            app.ZonesTab = uitab(app.TabGroup);
            app.ZonesTab.Title = 'Zones';
            app.UIZoneTable = uitable(app.ZonesTab);
            app.UIZoneTable.ColumnName = {'Liste'; 'Coordonnées'; 'Type'; 'Statut'; 'Temps de Violation'};
            app.UIZoneTable.RowName = {};
            app.AjoutZoneButton = uibutton(app.ZonesTab, 'push');
            app.AjoutZoneButton.Text = 'Ajout';
            app.ModificationZoneButton = uibutton(app.ZonesTab, 'push');
            app.ModificationZoneButton.Text = 'Modification';
            app.SuppressionZoneButton = uibutton(app.ZonesTab, 'push');
            app.SuppressionZoneButton.Text = 'Suppression';
            app.CiblesTab = uitab(app.TabGroup);
            app.CiblesTab.Title = 'Cibles';
            app.UICibleTable = uitable(app.CiblesTab);
            app.UICibleTable.ColumnName = {'Liste'; 'Coordonnées'; 'Flotte Allouée'; 'Statut'; 'Score d''Observabilité'};
            app.UICibleTable.RowName = {};
            app.AjoutCibleButton = uibutton(app.CiblesTab, 'push');
            app.AjoutCibleButton.Text = 'Ajout';
            app.ModificationCibleButton = uibutton(app.CiblesTab, 'push');
            app.ModificationCibleButton.Text = 'Modification';
            app.SuppressionCibleButton = uibutton(app.CiblesTab, 'push');
            app.SuppressionCibleButton.Text = 'Suppression';
            app.ComportementTab = uitab(app.TabGroup);
            app.ComportementTab.Title = 'Comportement';
            app.Panel = uipanel(app.UIFigure);
            app.Console = uitextarea(app.Panel);
            app.tempscouleEditField = uieditfield(app.Panel, 'text');
            app.dtSliderLabel = uilabel(app.Panel);
            app.dtSliderLabel.Text = 'dt';
            app.dtSlider = uislider(app.Panel);
            app.dtSlider.Limits = [0.03 10];
            app.dtSlider.ValueChangedFcn = createCallbackFcn(app, @dtSliderValueChanged, true);
            app.dtSlider.Value = 0.03;
            app.DronesdploysEditFieldLabel = uilabel(app.Panel);
            app.DronesdploysEditFieldLabel.Text = 'Drones déployés';
            app.DronesdploysEditField = uieditfield(app.Panel, 'numeric');
            app.DronesnondploysEditFieldLabel = uilabel(app.Panel);
            app.DronesnondploysEditFieldLabel.Text = 'Drones non déployés';
            app.DronesnondploysEditField = uieditfield(app.Panel, 'numeric');
            app.TempscoulLabel = uilabel(app.Panel);
            app.TempscoulLabel.Text = 'Temps écoulé';
            app.EventLoggingLabel = uilabel(app.Panel);
            app.EventLoggingLabel.Text = 'Event Logging';
            app.DronesperdusEditFieldLabel = uilabel(app.Panel);
            app.DronesperdusEditFieldLabel.Text = 'Drones perdus';
            app.DronesperdusEditField = uieditfield(app.Panel, 'numeric');
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PauseButton = uibutton(app.Panel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StartButton = uibutton(app.Panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Lamp = uilamp(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IHM_FINAL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end